classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ListBox                   matlab.ui.control.ListBox
        ControlPanel              matlab.ui.container.Panel
        PlayButton                matlab.ui.control.StateButton
        StopButton                matlab.ui.control.Button
        PreviousButton            matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        ShuffleButton             matlab.ui.control.Button
        RepeatButton              matlab.ui.control.Button
        Slider                    matlab.ui.control.Slider
        NowplayingEditFieldLabel  matlab.ui.control.Label
        NowplayingEditField       matlab.ui.control.EditField
        MutePanel                 matlab.ui.container.Panel
        MuteSwitch                matlab.ui.control.ToggleSwitch
        NotrackplayingLampLabel   matlab.ui.control.Label
        NotrackplayingLamp        matlab.ui.control.Lamp
        VolumeControlPanel        matlab.ui.container.Panel
        Knob                      matlab.ui.control.Knob
        StartLabel                matlab.ui.control.Label
        EndLabel                  matlab.ui.control.Label
        SelectgenreDropDownLabel  matlab.ui.control.Label
        SelectgenreDropDown       matlab.ui.control.DropDown
        MooshikTheultimatemusicdestinationLabel  matlab.ui.control.Label
        CreditsButton             matlab.ui.control.Button
    end


    properties (Access = public)
        base='C:\Users\arvindn\Music\MyMusic\'; % Base directory
        working_dir;
        index;
        player;
        volume;
        t_start;
        t_end;
    end

    methods (Access = public)
    
        function results = player_callback(app,player_audio,eventdata)
            app.Slider.Value=(100*player_audio.CurrentSample)/player_audio.TotalSamples;
            app.t_start=app.t_start+player_audio.TimerPeriod/60;
            start_min=floor(app.t_start);
            start_sec=floor((app.t_start-start_min)*60);
            app.StartLabel.Text=[num2str(start_min),':',num2str(start_sec)];
            app.t_end=app.t_end-player_audio.TimerPeriod/60;
            end_min=floor(app.t_end);
            end_sec=floor((app.t_end-end_min)*60);
            app.EndLabel.Text=[num2str(end_min),':',num2str(end_sec)];
        end
        
        function results = reset_slider(app)
            app.Slider.Value=0;
            app.StartLabel.Text='Start';
            app.EndLabel.Text='End';
        end
        
        function results = play_audio(app)
            disp('Playing...');
            app.PlayButton.Value=1;
            app.PlayButton.Text='Pause';
            app.NotrackplayingLampLabel.Text='Track playing';
            app.NotrackplayingLamp.Color=[0,1,0];
        end
        
        function results = pause_audio(app)
            disp('Song Paused');
            app.PlayButton.Text='Play';
            app.NotrackplayingLampLabel.Text='Track paused';
            app.NotrackplayingLamp.Color=[1,1,0];
            app.PlayButton.Value=0;
        end
        
        function results = stop_audio(app)
            disp('Stopping');
            app.PlayButton.Text='Play';
            app.NotrackplayingLampLabel.Text='No track playing';
            app.NotrackplayingLamp.Color=[1,0,0];
            app.PlayButton.Value=0;
            app.reset_slider();
        end
        
        function results = change_track(app,num)
            app.index=num;
            app.ListBox.Value=app.ListBox.Items{app.index};
            value=app.ListBox.Value;
            url = strcat(app.working_dir,value);
            app.NowplayingEditField.Value=value;
            [y,Fs] = audioread(url);
            app.player = audioplayer(y,Fs);
            app.t_start=0;
            app.t_end=app.player.TotalSamples/(60*app.player.SampleRate);
            app.player.TimerFcn=@app.player_callback;
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rng('shuffle');  
            addpath('H:\Documents\MATLAB\Mooshik\AppDesigner');
            files=dir(app.base);
            dirflag=[files.isdir];
            subfolders=files(dirflag);
            subfolders=subfolders(3:end);
            app.SelectgenreDropDown.Items={subfolders.name};
            value = app.SelectgenreDropDown.Value;
            app.working_dir=strcat(app.base,value,'\');
            files=dir(fullfile(app.working_dir,'*.mp3'));
            app.ListBox.Items={files.name};
            value = app.ListBox.Value;
            temp=cellfun(@(str)strcmp(str,value),app.ListBox.Items);
            app.index=find(temp);
            url = strcat(app.working_dir,value);
            app.NowplayingEditField.Value=value;
            [y,Fs] = audioread(url);
            app.player = audioplayer(y,Fs);
            app.volume=10000;
            app.t_start=0;
            app.t_end=app.player.TotalSamples/(60*app.player.SampleRate);
            cmd='nircmd.exe setsysvolume 20000';
            system(cmd);
            app.Knob.Value=(100*app.volume)/65535;
            app.player.TimerFcn=@(~,event)app.player_callback(app.player,event);
        end

        % Button pushed function: CreditsButton
        function CreditsButtonPushed(app, event)
            [cdata,map] = imread('dp.jpg'); 
            str=sprintf('Developed by: \nArvind Narayanan\n%s\n%s','MathWorks India Private Limited','Contact: arvind.narayanan@mathworks.in');
            h=msgbox(str,'Credits','custom',cdata,map);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            app.volume= (value*65535)/100;
            cmd=['nircmd.exe setsysvolume ',num2str(app.volume)];
            system(cmd);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            temp=cellfun(@(str)strcmp(str,value),app.ListBox.Items);
            app.index=find(temp);
            app.change_track(app.index);     
            app.stop_audio();
        end

        % Value changed function: MuteSwitch
        function MuteSwitchValueChanged(app, event)
            value = app.MuteSwitch.Value;
            if(strcmp(value,'On'))
                system('nircmd.exe mutesysvolume 1');
            else
                system('nircmd.exe mutesysvolume 0');
            end
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
             if(app.index==length(app.ListBox.Items))
                uialert(app.UIFigure,'No tracks after current track','Error');
            else
                app.change_track(app.index+1);     
                app.reset_slider();
                app.play_audio();
                resume(app.player);
            end 
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if(value==1)
                app.play_audio();
                resume(app.player);
            else
                app.pause_audio();
                pause(app.player);
            end
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if(app.index==1)
                uialert(app.UIFigure,'No tracks prior to current track','Error');
            else
                app.change_track(app.index-1);     
                app.reset_slider();
                app.play_audio();
                resume(app.player);
            end 
        end

        % Button pushed function: RepeatButton
        function RepeatButtonPushed(app, event)
             value = app.PlayButton.Value;
             if(value==0)
                disp('Repeating track');
                app.change_track(randi([1 length(app.ListBox.Items)]));     
                app.reset_slider();
                app.play_audio();
                play(app.player);
             else
                uialert(app.UIFigure,'Cannot repeat when track is playing','Error');
             end
             
        end

        % Value changed function: SelectgenreDropDown
        function SelectgenreDropDownValueChanged(app, event)
                value = app.SelectgenreDropDown.Value;
                app.working_dir=strcat(app.base,value,'\');
                files=dir(fullfile(app.working_dir,'*.mp3'));
                app.ListBox.Items={files.name};
        end

        % Button pushed function: ShuffleButton
        function ShuffleButtonPushed(app, event)
                app.change_track(randi([1 length(app.ListBox.Items)]));     
                app.reset_slider();
                app.play_audio();
                resume(app.player);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            value = app.PlayButton.Value;
            if(value==1)
                app.stop_audio();
                stop(app.player);
            else
                uialert(app.UIFigure,'No track playing to stop','Error');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.PlayButton = uibutton(app.ControlPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PreviousButton = uibutton(app.ControlPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.ControlPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ShuffleButton = uibutton(app.ControlPanel, 'push');
            app.ShuffleButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleButtonPushed, true);
            app.ShuffleButton.Text = 'Shuffle';
            app.RepeatButton = uibutton(app.ControlPanel, 'push');
            app.RepeatButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatButtonPushed, true);
            app.RepeatButton.Text = 'Repeat';
            app.Slider = uislider(app.ControlPanel);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.MinorTicks = [];
            app.NowplayingEditFieldLabel = uilabel(app.ControlPanel);
            app.NowplayingEditFieldLabel.Text = 'Now playing:';
            app.NowplayingEditField = uieditfield(app.ControlPanel, 'text');
            app.MutePanel = uipanel(app.ControlPanel);
            app.MutePanel.Title = 'Mute';
            app.MuteSwitch = uiswitch(app.MutePanel, 'toggle');
            app.MuteSwitch.ValueChangedFcn = createCallbackFcn(app, @MuteSwitchValueChanged, true);
            app.NotrackplayingLampLabel = uilabel(app.ControlPanel);
            app.NotrackplayingLampLabel.Text = 'No track playing';
            app.NotrackplayingLamp = uilamp(app.ControlPanel);
            app.VolumeControlPanel = uipanel(app.ControlPanel);
            app.VolumeControlPanel.Title = 'Volume Control';
            app.Knob = uiknob(app.VolumeControlPanel, 'continuous');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.StartLabel = uilabel(app.ControlPanel);
            app.StartLabel.Text = 'Start';
            app.EndLabel = uilabel(app.ControlPanel);
            app.EndLabel.Text = 'End';
            app.SelectgenreDropDownLabel = uilabel(app.UIFigure);
            app.SelectgenreDropDownLabel.Text = 'Select genre';
            app.SelectgenreDropDown = uidropdown(app.UIFigure);
            app.SelectgenreDropDown.Items = {'Select'};
            app.SelectgenreDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectgenreDropDownValueChanged, true);
            app.SelectgenreDropDown.Value = 'Select';
            app.MooshikTheultimatemusicdestinationLabel = uilabel(app.UIFigure);
            app.MooshikTheultimatemusicdestinationLabel.Text = 'Mooshik - The ultimate music destination!';
            app.CreditsButton = uibutton(app.UIFigure, 'push');
            app.CreditsButton.ButtonPushedFcn = createCallbackFcn(app, @CreditsButtonPushed, true);
            app.CreditsButton.Text = 'Credits';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end